classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        OpenSerialButton         matlab.ui.control.Button
        COMEditField             matlab.ui.control.NumericEditField
        COMEditFieldLabel        matlab.ui.control.Label
        SampleFreEditField       matlab.ui.control.NumericEditField
        SampleFreEditFieldLabel  matlab.ui.control.Label
        SerialLamp               matlab.ui.control.Lamp
        SerialLampLabel          matlab.ui.control.Label
        MeasureButton            matlab.ui.control.Button
        CloseSerial              matlab.ui.control.Button
        RightPanel               matlab.ui.container.Panel
        Image                    matlab.ui.control.Image
        mLEditField              matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        MeasureLamp              matlab.ui.control.Lamp
        MeasureLampLabel         matlab.ui.control.Label
        UIAxes4                  matlab.ui.control.UIAxes
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        mydata1 % 第一次敲击的数据
        mydata2 % 第二次敲击的数据
        Serial_obj % 串口对象
    end

    methods (Access = private)

        function [t, P1, f] = myFFT(app, sign)
            % FFT分析
            % 采样频率 单位Hz
            Fs = app.SampleFreEditField.Value;
            % 采样周期
            T = 1/Fs;
            if sign == 1
                final_data = app.mydata1;
            elseif sign == 2
                final_data = app.mydata2;
            else
            end
            % 信号总长度
            L = length(final_data);
            % Time vector
            t = (0 : L - 1) * T;
            %             % 绘制时域图像
            %             subplot(2, 2, 1);
            %             plot(t, final_data);
            %             % 绘制频域图像
            %             subplot(2, 2, 2);
            y = fft(final_data); % fft分解
            P2 = abs(y / L);
            P1 = P2(1:L/2+1);
            P1(2 : end - 1) = 2 * P1(2 : end - 1); % P1是频率的幅值
            f = Fs*(0:(L/2))/L; % f是频率横坐标轴
            %             plot(f, P1);
        end

        function [] = myWaterShow(app, realH)
            baker = imread('baker.png');
            image = imread('water.png');
            image = imresize(image,[651,578]);
            I = zeros(651,578,3);
            % 实际高度 * 445 / 500 得到h
            %             realH = 300;
            h = round(realH * 445 / 500); % 设定所需要的高度
            baker = im2double(baker);
            image = im2double(image);
            for i = 1:651
                for j = 1:578
                    if(i >= 651 - h)
                        I(i,j,:) = baker(i,j,:)*0.2 + image(i,j,:)*0.8;
                    else
                        I(i,j,:) = baker(i,j,:);
                    end
                end
            end
            app.Image.ImageSource = I;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
            app.MeasureButton.Enable = 'off';
            app.MeasureLamp.Color = 'blue';
            % 第一次敲击
            start_sign1 = 1; % 发送1 开始程序
            app.MeasureLamp.Color = 'red';
            pause(0.5);
            % 第一次数据采集开始
            data1 = zeros(1,90000);
            app.MeasureLamp.Color = 'blue';
            pause(0.5);
            fprintf(app.Serial_obj, '%d', start_sign1); % 给串口发一个
            number1 = fscanf(app.Serial_obj, '%d\r\n',256);
            for i = 1:number1
                data1(i) = fscanf(app.Serial_obj, '%d\r\n',256); % 读入数据
            end
            app.MeasureLamp.Color = 'green';
            pause(2);

            % 第二次敲
            start_sign2 = 2;
            app.MeasureLamp.Color = 'red';
            pause(0.5);
            % 第二次数据采集开始
            data2 = zeros(1,90000);
            app.MeasureLamp.Color = 'blue';
            pause(0.5);
            fprintf(app.Serial_obj, '%d', start_sign2);
            number2 = fscanf(app.Serial_obj, '%d\r\n',256);
            for i = 1:number2
                data2(i) = fscanf(app.Serial_obj, '%d\r\n',256); %读入数据
            end
            app.MeasureLamp.Color = 'green';
            pause(0.5);
            
            app.mydata1 = data1;
            app.mydata2 = data2;
            [t, P1, f] = myFFT(app, 1);
            plot(app.UIAxes, t, data1);
            plot(app.UIAxes2, f, P1);
            [t, P2, f] = myFFT(app, 2);
            plot(app.UIAxes3, t, data2);
            plot(app.UIAxes4, f, P2);
            app.MeasureLamp.Color = 'white';
            pause(0.5);
            low_fre = 400;
            high_fre = 850;
            [maxpeak, loc] = max(P1(low_fre*2+1 : high_fre*2+1));
            loc = loc + low_fre*2+1;
            fre_feature = (loc-1)/2;
            [y] = NeuralNet_Sidewall(fre_feature);
            app.mLEditField.Value = y;
            app.MeasureLamp.Color = 'green';
            myWaterShow(app, y);
            pause(0.5);
            app.MeasureButton.Enable = 'on';
            app.MeasureLamp.Color = 'red';

        end

        % Button pushed function: OpenSerialButton
        function OpenSerialButtonPushed(app, event)
            %% 开启串口
            delete(instrfindall); % 断开所有连接
            thisserial = app.COMEditField.Value;
            thisserial = num2str(thisserial);
            thisserial = ['COM' thisserial];
            app.Serial_obj = serial(thisserial);
            app.Serial_obj.BaudRate=600000;
            app.Serial_obj.DataBits=8;
            app.Serial_obj.Parity='none';
            app.Serial_obj.StopBits=1;
            app.Serial_obj.InputBufferSize = 1024;
            app.Serial_obj.OutputBufferSize = 1024;
            fopen(app.Serial_obj); % 打开串口
            if app.Serial_obj.Status == "open"
                app.SerialLamp.Color = 'green';
                app.MeasureLamp.Color = 'red';
                pause(0.5)
                app.MeasureButton.Enable = 'on';
                app.OpenSerialButton.Visible = 'off';
            else
            end
        end

        % Callback function
        function ButtonPushed(app, event)
            baker = imread('baker.png');
            image = imread('water.png');
            image = imresize(image,[651,578]);
            I = zeros(651,578,3);
            % 实际高度 * 445 / 500 得到h
            realH = 300;
            h = round(realH * 445 / 500); % 设定所需要的高度
            baker = im2double(baker);
            image = im2double(image);
            for i = 1:651
                for j = 1:578
                    if(i >= 651 - h)
                        I(i,j,:) = baker(i,j,:)*0.2 + image(i,j,:)*0.8;
                    else
                        I(i,j,:) = baker(i,j,:);
                    end
                end
            end
            app.Image.ImageSource = I;
        end

        % Button pushed function: CloseSerial
        function CloseSerialButtonPushed(app, event)
            app.OpenSerialButton.Visible = 'on';
            app.SerialLamp.Color = 'red';
            app.MeasureButton.Enable = 'off';
            delete(instrfindall);
        end

        % Button down function: LeftPanel
        function LeftPanelButtonDown(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {805, 805};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {264, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.ButtonDownFcn = createCallbackFcn(app, @LeftPanelButtonDown, true);
            app.CloseSerial = uibutton(app.LeftPanel, 'push');
            app.CloseSerial.ButtonPushedFcn = createCallbackFcn(app, @CloseSerialButtonPushed, true);
            app.CloseSerial.Text = 'CloseSerial';
            app.MeasureButton = uibutton(app.LeftPanel, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Enable = 'off';
            app.MeasureButton.Text = 'Measure';
            app.SerialLampLabel = uilabel(app.LeftPanel);
            app.SerialLampLabel.Text = {'SerialLamp'; ''};
            app.SerialLamp = uilamp(app.LeftPanel);
            app.SampleFreEditFieldLabel = uilabel(app.LeftPanel);
            app.SampleFreEditFieldLabel.Text = 'SampleFre';
            app.SampleFreEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SampleFreEditField.ValueDisplayFormat = '%.0f';
            app.SampleFreEditField.Value = 50000;
            app.COMEditFieldLabel = uilabel(app.LeftPanel);
            app.COMEditFieldLabel.Text = 'COM';
            app.COMEditField = uieditfield(app.LeftPanel, 'numeric');
            app.COMEditField.Value = 12;
            app.OpenSerialButton = uibutton(app.LeftPanel, 'push');
            app.OpenSerialButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSerialButtonPushed, true);
            app.OpenSerialButton.Text = 'OpenSerial';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'TopTime')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'TopFrequency')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'SidewallTime')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'SidewallFrequency')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.MeasureLampLabel = uilabel(app.RightPanel);
            app.MeasureLampLabel.Text = 'MeasureLamp';
            app.MeasureLamp = uilamp(app.RightPanel);
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '预测液量(mL)';
            app.mLEditField = uieditfield(app.RightPanel, 'numeric');
            app.Image = uiimage(app.RightPanel);
            app.Image.ImageSource = 'baker.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end